classdef KukaGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        KR6R700CRLabel      matlab.ui.control.Label
        YawTextArea         matlab.ui.control.TextArea
        YawTextAreaLabel    matlab.ui.control.Label
        PitchTextArea       matlab.ui.control.TextArea
        PitchTextAreaLabel  matlab.ui.control.Label
        RollTextArea        matlab.ui.control.TextArea
        RollTextAreaLabel   matlab.ui.control.Label
        KRZTextArea         matlab.ui.control.TextArea
        KRZTextAreaLabel    matlab.ui.control.Label
        KRYTextArea         matlab.ui.control.TextArea
        KRYTextAreaLabel    matlab.ui.control.Label
        KRXTextArea         matlab.ui.control.TextArea
        KRXTextAreaLabel    matlab.ui.control.Label
        ZEditField          matlab.ui.control.EditField
        ZEditFieldLabel     matlab.ui.control.Label
        YEditField          matlab.ui.control.EditField
        YEditFieldLabel     matlab.ui.control.Label
        XEditField          matlab.ui.control.EditField
        XEditFieldLabel     matlab.ui.control.Label
        MoveButton          matlab.ui.control.Button
        Joint6Slider        matlab.ui.control.Slider
        Joint6SliderLabel   matlab.ui.control.Label
        Joint5Slider        matlab.ui.control.Slider
        Joint5SliderLabel   matlab.ui.control.Label
        Joint4Slider        matlab.ui.control.Slider
        Joint4SliderLabel   matlab.ui.control.Label
        Joint3Slider        matlab.ui.control.Slider
        Joint3SliderLabel   matlab.ui.control.Label
        Joint2Slider        matlab.ui.control.Slider
        Joint2SliderLabel   matlab.ui.control.Label
        Joint1Slider        matlab.ui.control.Slider
        Joint1SliderLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        robotModel; % Instance of the KR6R700CR class
         jointAngles = [0 0 0 0 0 0];
    end
    
    methods (Access = private)
        
      function UpdateKRPose(app)
                pose = app.robotModel.model.fkine(app.jointAngles).T;
                rpy = tr2rpy(pose);
                app.RollTextArea.Value = num2str(rpy(1));
                app.PitchTextArea.Value = num2str(rpy(2));
                app.YawTextArea.Value = num2str(rpy(3));
    
                app.KRXTextArea.Value = num2str(pose(1, 4));
                app.KRYTextArea.Value = num2str(pose(2, 4));
                app.KRZTextArea.Value = num2str(pose(3, 4));
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.robotModel = KR6R700CR();
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            value = deg2rad(app.Joint1Slider.Value);
            app.jointAngles(1) = value;
            app.robotModel.model.animate(app.jointAngles);
            app.UpdateKRPose();
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            value = deg2rad(app.Joint2Slider.Value);
            app.jointAngles(2) = value;
            app.robotModel.model.animate(app.jointAngles);  
            app.UpdateKRPose();
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = deg2rad(app.Joint3Slider.Value);
            app.jointAngles(3) = value;
            app.robotModel.model.animate(app.jointAngles);   
            app.UpdateKRPose();
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            value = deg2rad(app.Joint4Slider.Value);
            app.jointAngles(4) = value;
            app.robotModel.model.animate(app.jointAngles);   
            app.UpdateKRPose();            
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            value = deg2rad(app.Joint5Slider.Value);
            app.jointAngles(5) = value;
            app.robotModel.model.animate(app.jointAngles);   
            app.UpdateKRPose();
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            value = deg2rad(app.Joint6Slider.Value);
            app.jointAngles(6) = value;
            app.robotModel.model.animate(app.jointAngles);   
            app.UpdateKRPose();
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            initialAngles = app.jointAngles;
            tolerance = 0.1;
            % Retrieve the x, y, z coordinates from the text fields
            x = str2double(app.XEditField.Value);
            y = str2double(app.YEditField.Value);
            z = str2double(app.ZEditField.Value);
            
            % Check if the coordinates are valid numbers
            if any(isnan([x, y, z]))
                % Display an error message if any coordinate is invalid
                uialert(app.UIFigure, 'Please enter valid coordinates.', 'Input Error');
                return;
            end
            
            T = transl(x, y, z); % transl is a function from the Robotics Toolbox
            
            % Calculate the joint angles using ikcon
            q = app.robotModel.model.ikcon(T);
            
            % Check if a valid solution is found
            if isempty(q)
                % Display an error message if no solution is found
                uialert(app.UIFigure, 'No valid joint configuration found for the given coordinates.', 'IK Error');
                return;
            end


            moveToGoal = jtraj(initialAngles,q,50);
            
            for i = 1:50
                % Animate the robot to the new joint angles
                app.robotModel.model.animate(moveToGoal(i,:));
                app.jointAngles = moveToGoal(i,:);
                app.UpdateKRPose();
                drawnow();
            end
            
            %tolerance check
            endPose = app.robotModel.model.fkine(app.jointAngles).T;
            if abs(x - endPose(1,4)) > tolerance || abs(y - endPose(2,4)) > tolerance || abs(z - endPose(3,4)) > tolerance
                msg = 'a suitable joint configuration for the given co-ordinates could not be found';
                title = 'ERROR: No solution found!';
                msgbox(msg,title);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-170 170];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-10 225];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-120 156];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-185 185];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-120 120];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-350 350];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.MoveButton = uibutton(app.UIFigure, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'text');
            app.KRXTextAreaLabel = uilabel(app.UIFigure);
            app.KRXTextAreaLabel.Text = 'KR X';
            app.KRXTextArea = uitextarea(app.UIFigure);
            app.KRYTextAreaLabel = uilabel(app.UIFigure);
            app.KRYTextAreaLabel.Text = 'KR Y';
            app.KRYTextArea = uitextarea(app.UIFigure);
            app.KRZTextAreaLabel = uilabel(app.UIFigure);
            app.KRZTextAreaLabel.Text = 'KR Z';
            app.KRZTextArea = uitextarea(app.UIFigure);
            app.RollTextAreaLabel = uilabel(app.UIFigure);
            app.RollTextAreaLabel.Text = 'Roll';
            app.RollTextArea = uitextarea(app.UIFigure);
            app.PitchTextAreaLabel = uilabel(app.UIFigure);
            app.PitchTextAreaLabel.Text = 'Pitch';
            app.PitchTextArea = uitextarea(app.UIFigure);
            app.YawTextAreaLabel = uilabel(app.UIFigure);
            app.YawTextAreaLabel.Text = 'Yaw';
            app.YawTextArea = uitextarea(app.UIFigure);
            app.KR6R700CRLabel = uilabel(app.UIFigure);
            app.KR6R700CRLabel.Text = 'KR6R700CR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KukaGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end