classdef eStop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ResumeButton          matlab.ui.control.Button
        DisengageEstopButton  matlab.ui.control.Button
        EStopButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        systemState = 'running'; % Possible states: 'running', 'eStopped', 'readyToResume'
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: EStopButton, UIFigure
        function EStopButtonPushed(app, event)
               app.systemState = 'eStopped';
            % Code to immediately stop operations
        end

        % Button pushed function: DisengageEstopButton
        function DisengageEstopButtonPushed(app, event)
             if strcmp(app.systemState, 'eStopped')
                app.systemState = 'readyToResume';
                disp('ready to resume');
            end
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            if strcmp(app.systemState, 'readyToResume')
                app.systemState = 'running';
                % Code to resume operations
            else
                disp('cannot resume operations. Must disengage E-stop first');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.DisengageEstopButton = uibutton(app.UIFigure, 'push');
            app.DisengageEstopButton.ButtonPushedFcn = createCallbackFcn(app, @DisengageEstopButtonPushed, true);
            app.DisengageEstopButton.Text = 'Disengage E-stop';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eStop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end